classdef KAM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConfirmButton                 matlab.ui.control.Button
        ParticipantIDEditField        matlab.ui.control.EditField
        ParticipantIDEditFieldLabel   matlab.ui.control.Label
        ImportFileButton              matlab.ui.control.Button
        SelectfileEditFieldLabel      matlab.ui.control.Label
        SelectfileEditField           matlab.ui.control.EditField
        Switch_2                      matlab.ui.control.Switch
        Trial5ButtonGroup             matlab.ui.container.ButtonGroup
        ToeInButton_9                 matlab.ui.control.ToggleButton
        MedialKneeThrustButton_5      matlab.ui.control.ToggleButton
        IpsilateralTrunkLeanButton_5  matlab.ui.control.ToggleButton
        WiderStepsButton_5            matlab.ui.control.ToggleButton
        ToeOutButton_5                matlab.ui.control.ToggleButton
        Trial4ButtonGroup             matlab.ui.container.ButtonGroup
        ToeInButton_7                 matlab.ui.control.ToggleButton
        MedialKneeThrustButton_4      matlab.ui.control.ToggleButton
        IpsilateralTrunkLeanButton_4  matlab.ui.control.ToggleButton
        WiderStepsButton_4            matlab.ui.control.ToggleButton
        ToeOutButton_4                matlab.ui.control.ToggleButton
        Trial3ButtonGroup             matlab.ui.container.ButtonGroup
        ToeOutButton_6                matlab.ui.control.ToggleButton
        MedialKneeThrustButton_3      matlab.ui.control.ToggleButton
        IpsilateralTrunkLeanButton_3  matlab.ui.control.ToggleButton
        WiderStepsButton_3            matlab.ui.control.ToggleButton
        ToeInButton_3                 matlab.ui.control.ToggleButton
        Trial2ButtonGroup             matlab.ui.container.ButtonGroup
        ToeInButton_8                 matlab.ui.control.ToggleButton
        MedialKneeThrustButton_2      matlab.ui.control.ToggleButton
        IpsilateralTrunkLeanButton_2  matlab.ui.control.ToggleButton
        WiderStepsButton_2            matlab.ui.control.ToggleButton
        ToeOutButton_2                matlab.ui.control.ToggleButton
        Trial1ButtonGroup             matlab.ui.container.ButtonGroup
        ToeInButton_6                 matlab.ui.control.ToggleButton
        MedialKneeThrustButton        matlab.ui.control.ToggleButton
        IpsilateralTrunkLeanButton    matlab.ui.control.ToggleButton
        WiderStepsButton              matlab.ui.control.ToggleButton
        ToeOutButton                  matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        Trial1 % Description
        Trial2 % Description
        Trial3 % Description
        Trial4 % Description
        Trial5 % Description
        Path % Description
        Subject % Description
        Count % Description
        Filename % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Count = "D";
            app.Trial1 = "Toe In";
            app.Trial2 = "Toe Out";
            app.Trial3 = "Wider Steps";
            app.Trial4 = "Ipsilateral Trunk Lean";
            app.Trial5 = "Medial Knee Thrust";
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [filename, path] = uigetfile('.txt','Select KAM Data');
            if filename == 0
                app.delete
                warning("GUIApp:AddAFile","Please select a file before continuing.")
                return
            end
            app.Filename = filename;
            app.SelectfileEditField.Value = filename;
            app.Path = path;
            msgbox('\fontsize{18} You have been redirected from the app window, please return to the app. (Press Ctrl + Tab / Cmnd + Tab)','Alert','warn',struct("Interpreter",'tex',"WindowStyle",'modal'))
        end

        % Value changed function: SelectfileEditField
        function SelectfileEditFieldValueChanged(app, event)
            app.Filename = app.SelectfileEditField.Value;
            
        end

        % Value changed function: ParticipantIDEditField
        function ParticipantIDEditFieldValueChanged(app, event)
            app.Subject = app.ParticipantIDEditField.Value;
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            if app.Switch_2.Value == 'Count Up to 300'
                app.Count = "U";
            elseif app.Switch_2.Value == 'Count Down to 0 (Default)'
                app.Count = "D";
            else
                app.Count = app.Switch_2.Value;
            end
        end

        % Selection changed function: Trial5ButtonGroup
        function Trial5ButtonGroupSelectionChanged(app, event)
            app.Trial5 = join(string(app.Trial5ButtonGroup.SelectedObject.Text));
            
        end

        % Selection changed function: Trial4ButtonGroup
        function Trial4ButtonGroupSelectionChanged(app, event)
            app.Trial4 = join(string(app.Trial4ButtonGroup.SelectedObject.Text));
            
        end

        % Selection changed function: Trial3ButtonGroup
        function Trial3ButtonGroupSelectionChanged(app, event)
            app.Trial3 = join(string(app.Trial3ButtonGroup.SelectedObject.Text));
            
        end

        % Selection changed function: Trial2ButtonGroup
        function Trial2ButtonGroupSelectionChanged(app, event)
            app.Trial2 = join(string(app.Trial2ButtonGroup.SelectedObject.Text));
            
        end

        % Selection changed function: Trial1ButtonGroup
        function Trial1ButtonGroupSelectionChanged(app, event)
            app.Trial1 = join(string(app.Trial1ButtonGroup.SelectedObject.Text));
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            active = struct("Trial1",app.Trial1,"Trial2",app.Trial2,"Trial3",app.Trial3,"Trial4",app.Trial4,"Trial5",app.Trial5,"Filename",app.Filename,"Subject",app.Subject,"Path",app.Path,"Count",app.Count);
            assignin('base', 'active', active)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Trial1ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trial1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Trial1ButtonGroupSelectionChanged, true);
            app.Trial1ButtonGroup.Title = 'Trial 1';
            app.ToeOutButton = uitogglebutton(app.Trial1ButtonGroup);
            app.ToeOutButton.Text = 'Toe Out';
            app.WiderStepsButton = uitogglebutton(app.Trial1ButtonGroup);
            app.WiderStepsButton.Text = 'Wider Steps';
            app.IpsilateralTrunkLeanButton = uitogglebutton(app.Trial1ButtonGroup);
            app.IpsilateralTrunkLeanButton.Text = {'Ipsilateral'; 'Trunk Lean'};
            app.MedialKneeThrustButton = uitogglebutton(app.Trial1ButtonGroup);
            app.MedialKneeThrustButton.Text = {'Medial Knee'; 'Thrust'};
            app.ToeInButton_6 = uitogglebutton(app.Trial1ButtonGroup);
            app.ToeInButton_6.Text = 'Toe In';
            app.ToeInButton_6.Value = true;
            app.Trial2ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trial2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Trial2ButtonGroupSelectionChanged, true);
            app.Trial2ButtonGroup.Title = 'Trial 2';
            app.ToeOutButton_2 = uitogglebutton(app.Trial2ButtonGroup);
            app.ToeOutButton_2.Text = 'Toe Out';
            app.ToeOutButton_2.Value = true;
            app.WiderStepsButton_2 = uitogglebutton(app.Trial2ButtonGroup);
            app.WiderStepsButton_2.Text = 'Wider Steps';
            app.IpsilateralTrunkLeanButton_2 = uitogglebutton(app.Trial2ButtonGroup);
            app.IpsilateralTrunkLeanButton_2.Text = {'Ipsilateral'; 'Trunk Lean'};
            app.MedialKneeThrustButton_2 = uitogglebutton(app.Trial2ButtonGroup);
            app.MedialKneeThrustButton_2.Text = {'Medial Knee'; 'Thrust'};
            app.ToeInButton_8 = uitogglebutton(app.Trial2ButtonGroup);
            app.ToeInButton_8.Text = 'Toe In';
            app.Trial3ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trial3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Trial3ButtonGroupSelectionChanged, true);
            app.Trial3ButtonGroup.Title = 'Trial 3';
            app.ToeInButton_3 = uitogglebutton(app.Trial3ButtonGroup);
            app.ToeInButton_3.Text = 'Toe In';
            app.WiderStepsButton_3 = uitogglebutton(app.Trial3ButtonGroup);
            app.WiderStepsButton_3.Text = 'Wider Steps';
            app.WiderStepsButton_3.Value = true;
            app.IpsilateralTrunkLeanButton_3 = uitogglebutton(app.Trial3ButtonGroup);
            app.IpsilateralTrunkLeanButton_3.Text = {'Ipsilateral'; 'Trunk Lean'};
            app.MedialKneeThrustButton_3 = uitogglebutton(app.Trial3ButtonGroup);
            app.MedialKneeThrustButton_3.Text = {'Medial Knee'; 'Thrust'};
            app.ToeOutButton_6 = uitogglebutton(app.Trial3ButtonGroup);
            app.ToeOutButton_6.Text = 'Toe Out';
            app.Trial4ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trial4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Trial4ButtonGroupSelectionChanged, true);
            app.Trial4ButtonGroup.Title = 'Trial 4';
            app.ToeOutButton_4 = uitogglebutton(app.Trial4ButtonGroup);
            app.ToeOutButton_4.Text = 'Toe Out';
            app.WiderStepsButton_4 = uitogglebutton(app.Trial4ButtonGroup);
            app.WiderStepsButton_4.Text = 'Wider Steps';
            app.IpsilateralTrunkLeanButton_4 = uitogglebutton(app.Trial4ButtonGroup);
            app.IpsilateralTrunkLeanButton_4.Text = {'Ipsilateral'; 'Trunk Lean'};
            app.IpsilateralTrunkLeanButton_4.Value = true;
            app.MedialKneeThrustButton_4 = uitogglebutton(app.Trial4ButtonGroup);
            app.MedialKneeThrustButton_4.Text = {'Medial Knee'; 'Thrust'};
            app.ToeInButton_7 = uitogglebutton(app.Trial4ButtonGroup);
            app.ToeInButton_7.Text = 'Toe In';
            app.Trial5ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trial5ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Trial5ButtonGroupSelectionChanged, true);
            app.Trial5ButtonGroup.Title = 'Trial 5';
            app.ToeOutButton_5 = uitogglebutton(app.Trial5ButtonGroup);
            app.ToeOutButton_5.Text = 'Toe Out';
            app.WiderStepsButton_5 = uitogglebutton(app.Trial5ButtonGroup);
            app.WiderStepsButton_5.Text = 'Wider Steps';
            app.IpsilateralTrunkLeanButton_5 = uitogglebutton(app.Trial5ButtonGroup);
            app.IpsilateralTrunkLeanButton_5.Text = {'Ipsilateral'; 'Trunk Lean'};
            app.MedialKneeThrustButton_5 = uitogglebutton(app.Trial5ButtonGroup);
            app.MedialKneeThrustButton_5.Text = {'Medial Knee'; 'Thrust'};
            app.MedialKneeThrustButton_5.Value = true;
            app.ToeInButton_9 = uitogglebutton(app.Trial5ButtonGroup);
            app.ToeInButton_9.Text = 'Toe In';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Count Down to 0 (Default)', 'Count Up to 300'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Count Down to 0 (Default)';
            app.SelectfileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectfileEditField.ValueChangedFcn = createCallbackFcn(app, @SelectfileEditFieldValueChanged, true);
            app.SelectfileEditField.Editable = 'off';
            app.SelectfileEditFieldLabel = uilabel(app.UIFigure);
            app.SelectfileEditFieldLabel.Text = 'Select file';
            app.ImportFileButton = uibutton(app.UIFigure, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.ParticipantIDEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.UIFigure, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDEditFieldValueChanged, true);
            app.ParticipantIDEditField.Value = 'S...';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KAM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end